classdef App_ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        log          matlab.ui.control.Button
        linear       matlab.ui.control.Button
        DropDown     matlab.ui.control.DropDown
        Label_4      matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Label        matlab.ui.control.Label
        Button       matlab.ui.control.Button
        output_corr  matlab.ui.control.UIAxes
        output_SPM   matlab.ui.control.UIAxes
        output       matlab.ui.control.UIAxes
        input_corr   matlab.ui.control.UIAxes
        Input_SPM    matlab.ui.control.UIAxes
        input        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % variables
        Fs=250;
        tmax=5;
        tcor=2.5;
        pxx
        px
        pxlin
        pylin
        pxl
        pxy
        py
        pyl
        value
        valuelog
        valueS
        x
        y
        helpvalue
        autoX
        autoY
        tcf
        f
       
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            clc

% 9.1 Чтение сигнала из заданного файла

filename=uigetfile('*.txt');
app.x=load(filename);
Fs=250;


%Элементы выбора становятся активными
app.DropDown.Enable=true;
app.log.Enable=true;
app.linear.Enable=false;

% 9.2 Отображение графиков исходного сигнала 
T=1/app.Fs;
app.tmax=5;
t=0:T:app.tmax-T;
plot(app.input,t,app.x);
%set(app.input,'Xlim',[0 app.Fs/2]);
%hold on

% 9.3 Фильтрация с помощью фильтра 
load('matlab_coef.mat');
Den = 1;
app.y=filter(Num,Den, app.x);

% 9.4 Отображение графиков после фильтрации
plot(app.output,t,app.y);
%set(app.output,'Xlim',[0 app.Fs/2]);

%СПМ по исходным параметрам INPUT
app.value = app.DropDown.Value;
app.value=str2num(app.value);
nfft = 2048;
% app.pxx = pwelch(app.x,app.value,0.7,nfft);
% [P1,f] = pwelch(x,hamming(100),70,2048,Fs);
[app.pxx, app.f] = pwelch(app.x,hamming(app.value),70,2048,app.Fs);
plot(app.Input_SPM,app.f,app.pxx);
set(app.Input_SPM,'Xlim',[0 60]);

 
 %СПМ по исходным параметрам OUTPUT
%  app.pxy=pwelch(app.y,app.value,0.7,nfft);
%  plot(app.output_SPM,app.pxy);

[app.pxy, app.f] = pwelch(app.y,hamming(app.value),70,2048,app.Fs);
plot(app.output_SPM,app.f,app.pxy);
 set(app.output_SPM,'Xlim',[0 60]);

 
 %Оценка автокорреляции 
 app.tcf=-app.tmax+T:T:app.tmax-T;
 app.autoX=xcorr(app.x,'coef');
 set(app.input_corr,'Xlim',[-2.5 2.5]);
plot(app.input_corr,app.tcf,app.autoX);

app.autoY=xcorr(app.y,'coef');
 set(app.output_corr,'Xlim',[-2.5 2.5]);
plot(app.output_corr,app.tcf,app.autoY);

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
global valuek  

            app.value = app.DropDown.Value;
             app.value=str2num(app.value);
%               valuek = app.DropDown.Value;
%              valuek=str2num(app.value);
             
            nfft = 2048;
if app.helpvalue == 1
valuek = app.DropDown.Value;
valuek=str2num(valuek);
% app.pxlin=pwelch(app.x,valuek,0.7,nfft);
% plot(app.Input_SPM,app.pxlin);

[app.pxlin, app.f] = pwelch(app.x,hamming(app.value),70,2048,app.Fs);
plot(app.Input_SPM,app.f,app.pxlin);
set(app.Input_SPM,'Xlim',[0 60]);
% app.pylin=pwelch(app.y,valuek,0.7,nfft);
% plot(app.output_SPM,app.pylin); 

[app.pylin, app.f] = pwelch(app.y,hamming(app.value),70,2048,app.Fs);
plot(app.output_SPM,app.f,app.pylin);
set(app.output_SPM,'Xlim',[0 60]);

             
              else
               valuek = app.DropDown.Value;
valuek=str2num(valuek);
% app.pxlin=pwelch(app.x,valuek,0.7,nfft);
[app.pxlin, app.f] = pwelch(app.x,hamming(app.value),70,2048,app.Fs);

app.pxlin=log10(app.pxlin);
plot(app.Input_SPM,app.f,app.pxlin);
% plot(app.Input_SPM,app.pxlin);


set(app.Input_SPM,'Xlim',[0 60]);
% app.pylin=pwelch(app.y,valuek,0.7,nfft);
[app.pylin, app.f] = pwelch(app.y,hamming(app.value),70,2048,app.Fs);

app.pylin=log10(app.pylin);
plot(app.output_SPM,app.f,app.pylin);

% plot(app.output_SPM,app.pylin); 
set(app.output_SPM,'Xlim',[0 60]);

              end 
       
        end

        % Callback function
        function Button_2Pushed(app, event)
            % 9.5 СПМ
%             app.pxx=pwelch(app.x,12);
% plot(app.output_SPM,app.pxx);
% set(app.output_SPM,'Xlim',[0 60]);

        end

        % Callback function
        function SwitchValueChanged(app, event)
            app.valueS = app.Switch.Value;
       % app.tf=strcmp(app.valueS,'1');
      
        
        end

        % Button pushed function: log
        function logButtonPushed(app, event)

app.log.Enable=false;
app.linear.Enable=true;

app.helpvalue=2;
% app.valuelog = app.DropDown.Value;
% app.valuelog=str2num(app.valuelog);
app.value = app.DropDown.Value;
app.value=str2num(app.value);
nfft = 2048;
% app.pxl = pwelch(app.x,app.valuelog,0.7,nfft)
[app.pxl, app.f] = pwelch(app.x,hamming(app.value),70,2048,app.Fs);

%app.pxl=pmcov(app.x,app.valuelog);
app.pxl=log10(app.pxl);

plot(app.Input_SPM,app.f,app.pxl);
% plot(app.Input_SPM,app.pxl);
set(app.Input_SPM,'Xlim',[0 60]);


% app.pyl = pwelch(app.y,app.valuelog,0.7,nfft)

[app.pyl, app.f] = pwelch(app.y,hamming(app.value),70,2048,app.Fs);
%app.pyl=pmcov(app.y,app.valuelog);
app.pyl=log10(app.pyl);
plot(app.output_SPM,app.f,app.pyl);
% plot(app.output_SPM,app.pyl);
set(app.output_SPM,'Xlim',[0 60]);
            
        end

        % Button pushed function: linear
        function linearButtonPushed(app, event)
            %Активация\дизактивация элементов
%             app.Input_SPM.YScale = 'linear';
% app.output_SPM.YScale = 'linear';

app.log.Enable=true;
app.linear.Enable=false; 

app.helpvalue=1;
app.value = app.DropDown.Value;
app.value=str2num(app.value);
nfft = 2048;
% app.px = pwelch(app.x,app.value,0.7,nfft)

[app.px, app.f] = pwelch(app.x,hamming(app.value),70,2048,app.Fs);
% app.px=pmcov(app.x,app.value);
% plot(app.Input_SPM,app.px);
plot(app.Input_SPM,app.f,app.px);
set(app.Input_SPM,'Xlim',[0 60]);
% app.py = pwelch(app.y,app.value,0.7,nfft)
[app.py, app.f] = pwelch(app.y,hamming(app.value),70,2048,app.Fs);
%app.py=pmcov(app.y,app.value);
% plot(app.output_SPM,app.py); 
plot(app.output_SPM,app.f,app.py);
set(app.output_SPM,'Xlim',[0 60]);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.input = uiaxes(app.UIFigure);
            title(app.input, 'Входной сигнал')
            xlabel(app.input, 'X')
            ylabel(app.input, 'Y')
            app.Input_SPM = uiaxes(app.UIFigure);
            title(app.Input_SPM, 'СПМ входного сигнала')
            xlabel(app.Input_SPM, 'X')
            ylabel(app.Input_SPM, 'Y')
            app.input_corr = uiaxes(app.UIFigure);
            title(app.input_corr, 'АКФ входного сигнала')
            xlabel(app.input_corr, 'X')
            ylabel(app.input_corr, 'Y')
            app.output = uiaxes(app.UIFigure);
            title(app.output, 'Выходной сигнал')
            xlabel(app.output, 'X')
            ylabel(app.output, 'Y')
            app.output_SPM = uiaxes(app.UIFigure);
            title(app.output_SPM, 'СПМ выходного сигнала')
            xlabel(app.output_SPM, 'X')
            ylabel(app.output_SPM, 'Y')
            app.output_corr = uiaxes(app.UIFigure);
            title(app.output_corr, 'АКФ выходного сигнала')
            xlabel(app.output_corr, 'X')
            ylabel(app.output_corr, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Загрузить';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Исходный сигнал';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Сигнал после фильтрации';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = 'Выбор размера сегмента';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'100', '200', '500'};
            app.DropDown.ItemsData = {'100', '200', '500'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = '100';
            app.linear = uibutton(app.UIFigure, 'push');
            app.linear.ButtonPushedFcn = createCallbackFcn(app, @linearButtonPushed, true);
            app.linear.Enable = 'off';
            app.linear.Text = 'Linear';
            app.log = uibutton(app.UIFigure, 'push');
            app.log.ButtonPushedFcn = createCallbackFcn(app, @logButtonPushed, true);
            app.log.Enable = 'off';
            app.log.Text = 'Log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end